classdef ICEMAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel_9                       matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        RcommendationFactorLabel_2    matlab.ui.control.Label
        IntelligentCharacterizationExtractionMachineLabel  matlab.ui.control.Label
        rfEditField                   matlab.ui.control.NumericEditField
        rfEditFieldLabel              matlab.ui.control.Label
        Panel_4                       matlab.ui.container.Panel
        UIAxes_2                      matlab.ui.control.UIAxes
        Panel_3                       matlab.ui.container.Panel
        Panel_8                       matlab.ui.container.Panel
        ParametersLabel_2             matlab.ui.control.Label
        Panel_7                       matlab.ui.container.Panel
        BestFitDistributionLabel_2    matlab.ui.control.Label
        UITable3                      matlab.ui.control.Table
        NameEditField                 matlab.ui.control.EditField
        NameEditFieldLabel            matlab.ui.control.Label
        Panel_2                       matlab.ui.container.Panel
        LabelEditField                matlab.ui.control.EditField
        LabelEditFieldLabel           matlab.ui.control.Label
        CalculationButton             matlab.ui.control.Button
        ConfidentLevelEditField       matlab.ui.control.NumericEditField
        LabelEditField_2Label         matlab.ui.control.Label
        Button                        matlab.ui.control.Button
        AddressEditField              matlab.ui.control.EditField
        AddressEditFieldLabel         matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        SecondMomentPanel             matlab.ui.container.Panel
        COVEditField                  matlab.ui.control.NumericEditField
        COVEditFieldLabel             matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        StdEditField                  matlab.ui.control.NumericEditField
        StdEditFieldLabel             matlab.ui.control.Label
        FirstMomentPanel              matlab.ui.container.Panel
        medianEditField_2             matlab.ui.control.NumericEditField
        medianEditFieldLabel_2        matlab.ui.control.Label
        MeanEditField                 matlab.ui.control.NumericEditField
        MeanEditFieldLabel            matlab.ui.control.Label
        Panel_5                       matlab.ui.container.Panel
        CharacteristicParameterLabel  matlab.ui.control.Label
        ICEMLabel                     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculationButton
        function CalculationButtonPushed(app, event)

            tableData=xlsread(app.AddressEditField.Value);
            p=app.ConfidentLevelEditField.Value;
            label=app.LabelEditField.Value;
            
            %运行icw算法
               [ D,cp,bestfit,gof,rf,pd,n,parameter1] = icm(  tableData,label,p );
%              [ cp,bestfit,gof,rf]=icm(tableData,param1,param2);
            %% 输出特征参数
            app.MeanEditField.Value=cp(1);
            app.StdEditField.Value=cp(2);
            app.COVEditField.Value=cp(3);
            app.medianEditField_2.Value=cp(4);
            %% 输出最优拟合分布
            % 输出Name
            app.NameEditField.Value = bestfit{1}{1};
            % 输出parameters列表
            app.UITable3.Data = bestfit{1,2};
            %% 输出Gof
%             app.UITable.Data = cell(size(app.UITable.Data));
%             
%             
%             rows_to_display = gof(:, 1) > 0.05;
%             app.UITable.Data(1:sum(rows_to_display),2:4) = num2cell(gof(rows_to_display, 2:4));
% 
%             label_name = {'Normal', 'Lognormal', 'Gumbel', 'Frechet', 'Weibull', 'GMM'};
%             h = find(gof(:, 1) > 0.05);
% 
%             % 检查是否有符合条件的索引
%             if ~isempty(h)
%                 % 如果有符合条件的索引，将 label_name 中对应索引的值转换为 cell 数组
%                 label1 = cellstr(label_name(h));
%                 app.UITable.Data(:, 1) = label1;
%             end
           
            %% 输出p值
%             app.UITable4.Data = cell(size(app.UITable4.Data));
%             app.UITable4.Data(1:3,1)={'Normal';'Lognormal';'Gummbel'};
%             app.UITable4.Data(1:3,3)={'Frechet';'Weibull';'GMM'};
% 
%             app.UITable4.Data(:, 2) = num2cell(gof(1:3, 1));
%             app.UITable4.Data(:, 4) = num2cell(gof(4:6, 1));
            %% 输出rf
            app.rfEditField.Value=rf;

            %% 显示图像
            A=app.UIAxes;
            hist_figure_chun(tableData,pd,n,parameter1,A);

            %% 显示p的散点图
            [numRows, ~] = size(tableData);
            positiveNumbers = 1:1:numRows;
            G = [positiveNumbers' tableData];
            B=app.UIAxes_2;
            pointchart(G,B,numRows)

%             %% 显示gof蜘蛛图
%             % label_name={'Normal','Lognormal','ExtremeValue','Frechet','Weibull','GMM'};
%             % D{1,1}=label;
%             col=find( gof(:,1)>0.05);
%             [~,index(1)] = min(gof(col,2));
%             [~,index(2)] = min(gof(col,3));
%             [~,index(3)] = min(gof(col,4));
%             % D{:,2}=label_name(col(index(1)));
%             % D{:,3}=label_name(col(index(2)));
%             % D{:,4}=label_name(col(index(3)));
%             a=tabulate(index);
%             x=find(a(:,2)>=2); % Finding the distribution number with cumulative points greater than 2.
%             n=col(a(x));
%             rows_to_display = gof(:, 1) > 0.05;
%             h = find(rows_to_display);
%             h_transposed = h';
%             selected_columns = gof(:, 2:4);
%             C=app.UIAxes2;
%             spider( selected_columns,n,h_transposed);


        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
             [fileName, filePath] = uigetfile({'*.xlsx';'*.xls'}, '选择数据文件');
            if isequal(fileName, 0)
                % 用户取消了选择文件
                return;
            end
            % 获取文件路径
            fullFilePath = fullfile(filePath, fileName);

            % 设置 app.AddressEditField 的值为文件路径
            app.AddressEditField.Value = fullFilePath;
           

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ICEMLabel = uilabel(app.UIFigure);
            app.ICEMLabel.Text = 'ICEM';
            app.Panel = uipanel(app.UIFigure);
            app.Panel_5 = uipanel(app.Panel);
            app.CharacteristicParameterLabel = uilabel(app.Panel_5);
            app.CharacteristicParameterLabel.Text = 'Characteristic Parameter';
            app.FirstMomentPanel = uipanel(app.Panel);
            app.FirstMomentPanel.Title = 'First Moment';
            app.MeanEditFieldLabel = uilabel(app.FirstMomentPanel);
            app.MeanEditFieldLabel.Text = 'Mean';
            app.MeanEditField = uieditfield(app.FirstMomentPanel, 'numeric');
            app.MeanEditField.ValueDisplayFormat = '%.3f';
            app.medianEditFieldLabel_2 = uilabel(app.FirstMomentPanel);
            app.medianEditFieldLabel_2.Text = 'median';
            app.medianEditField_2 = uieditfield(app.FirstMomentPanel, 'numeric');
            app.medianEditField_2.ValueDisplayFormat = '%.3f';
            app.SecondMomentPanel = uipanel(app.Panel);
            app.SecondMomentPanel.Title = 'Second Moment';
            app.StdEditFieldLabel = uilabel(app.SecondMomentPanel);
            app.StdEditFieldLabel.Text = 'Std';
            app.StdEditField = uieditfield(app.SecondMomentPanel, 'numeric');
            app.StdEditField.ValueDisplayFormat = '%.3f';
            app.Label = uilabel(app.SecondMomentPanel);
            app.Label.Text = '%';
            app.COVEditFieldLabel = uilabel(app.SecondMomentPanel);
            app.COVEditFieldLabel.Text = 'COV';
            app.COVEditField = uieditfield(app.SecondMomentPanel, 'numeric');
            app.COVEditField.ValueDisplayFormat = '%.3f';
            app.Panel_2 = uipanel(app.UIFigure);
            app.AddressEditFieldLabel = uilabel(app.Panel_2);
            app.AddressEditFieldLabel.Text = 'Address';
            app.AddressEditField = uieditfield(app.Panel_2, 'text');
            app.Button = uibutton(app.Panel_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'tubiao.png');
            app.Button.Text = '';
            app.LabelEditField_2Label = uilabel(app.Panel_2);
            app.LabelEditField_2Label.Text = 'Confident Level';
            app.ConfidentLevelEditField = uieditfield(app.Panel_2, 'numeric');
            app.ConfidentLevelEditField.ValueDisplayFormat = '%.3f';
            app.ConfidentLevelEditField.Value = 0.5;
            app.CalculationButton = uibutton(app.Panel_2, 'push');
            app.CalculationButton.ButtonPushedFcn = createCallbackFcn(app, @CalculationButtonPushed, true);
            app.CalculationButton.Text = 'Calculation';
            app.LabelEditFieldLabel = uilabel(app.Panel_2);
            app.LabelEditField = uieditfield(app.Panel_2, 'text');
            app.LabelEditField.Value = 'test';
            app.Panel_3 = uipanel(app.UIFigure);
            app.NameEditFieldLabel = uilabel(app.Panel_3);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.Panel_3, 'text');
            app.UITable3 = uitable(app.Panel_3);
            app.UITable3.ColumnName = {'N1'; 'N2'; 'N3'};
            app.UITable3.RowName = {'μ'; 'σ'; 'ω '};
            app.Panel_7 = uipanel(app.Panel_3);
            app.BestFitDistributionLabel_2 = uilabel(app.Panel_7);
            app.BestFitDistributionLabel_2.Text = 'Best Fit Distribution';
            app.Panel_8 = uipanel(app.Panel_3);
            app.ParametersLabel_2 = uilabel(app.Panel_8);
            app.ParametersLabel_2.Text = 'Parameters';
            app.Panel_4 = uipanel(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.Panel_4);
            xlabel(app.UIAxes_2, 'Numbel')
            ylabel(app.UIAxes_2, 'PA')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [0 2.5];
            app.UIAxes_2.YLim = [0 25];
            app.UIAxes_2.XTick = [0 0.4 0.8 1.2 1.6 2 2.4];
            app.UIAxes_2.XTickLabel = {'0'; '30'; '60'; '90'; '120'; '150'; '180'};
            app.UIAxes_2.YTickLabel = {'0'; '0.5'; '1'; '1.5'; '2'; '2.5'};
            app.rfEditFieldLabel = uilabel(app.UIFigure);
            app.rfEditFieldLabel.Text = 'rf';
            app.rfEditField = uieditfield(app.UIFigure, 'numeric');
            app.rfEditField.ValueDisplayFormat = '%.3f';
            app.IntelligentCharacterizationExtractionMachineLabel = uilabel(app.UIFigure);
            app.IntelligentCharacterizationExtractionMachineLabel.Text = 'Intelligent Characterization Extraction Machine ';
            app.RcommendationFactorLabel_2 = uilabel(app.UIFigure);
            app.RcommendationFactorLabel_2.Text = 'Rcommendation Factor';
            app.Panel_9 = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel_9);
            xlabel(app.UIAxes, '{\itPA}')
            ylabel(app.UIAxes, 'Frequency/%')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 2.5];
            app.UIAxes.YLim = [0 25];
            app.UIAxes.XTick = [0 0.5 1 1.5 2 2.5];
            app.UIAxes.XTickLabel = {'0'; '0.5'; '1'; '1.5'; '2'; '2.5'};
            app.UIAxes.YTick = [0 5 10 15 20 25];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ICEMAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end